classdef thermo_cam_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThermoCamAnalyzerUIFigure  matlab.ui.Figure
        Label_statusMessage        matlab.ui.control.Label
        Label_videoFileName        matlab.ui.control.Label
        Panel_ImagePreview         matlab.ui.container.Panel
        Slider_XCursor             matlab.ui.control.Slider
        Slider_YCursor             matlab.ui.control.Slider
        UIAxes_Image               matlab.ui.control.UIAxes
        Panel_TimeSeries           matlab.ui.container.Panel
        Slider_ViewIndex           matlab.ui.control.Slider
        CountSliderLabel           matlab.ui.control.Label
        UIAxes_TemperPlot          matlab.ui.control.UIAxes
        Panel_3                    matlab.ui.container.Panel
        Button_Export              matlab.ui.control.Button
        Label_4                    matlab.ui.control.Label
        Button_ViewDetail          matlab.ui.control.Button
        Label_3                    matlab.ui.control.Label
        Panel_2                    matlab.ui.container.Panel
        EditField_Dulations        matlab.ui.control.NumericEditField
        DulationLabel              matlab.ui.control.Label
        EditField_TempMin          matlab.ui.control.NumericEditField
        TempMinEditFieldLabel      matlab.ui.control.Label
        EditField_TempMax          matlab.ui.control.NumericEditField
        TempMaxEditFieldLabel      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        Button_MakeProxy           matlab.ui.control.Button
        EditField_decimateNum      matlab.ui.control.NumericEditField
        DecimateCountLabel         matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Button_SelectVideoFile     matlab.ui.control.Button
    end

    
    properties (Access = private)
        videoObj = 0; % Video file object
        proxyImages_max = []; % Proxy data
        proxyImages_min = []; % Proxy data
        proxyLength = 0;
        decimateNum = 1;
        temp_max = 100;
        temp_min = 0;
        dulation = 1;
        cursor_x = 1;
        cursor_y = 1;
        previewIdx = 1;
        detail_time = [];
        detail_temp = [];
        detail_location = [1, 1];
    end
    
    methods (Access = private)
        
        function refreshPreviewImage(app)

            %% 画像プレビューの更新

            % 画像更新
            image = (app.proxyImages_max(:, :, app.previewIdx) + app.proxyImages_min(:, :, app.previewIdx)) ./ 2;
            imshow(uint8(image), 'Parent', app.UIAxes_Image);
            hold(app.UIAxes_Image, 'on');
            % カーソル更新
            xline(app.UIAxes_Image, app.cursor_x, 'g');
            yline(app.UIAxes_Image, app.cursor_y, 'g');
            hold(app.UIAxes_Image, 'off');

            axis(app.UIAxes_Image, 'equal');
            %box(app.UIAxes_Image, 'on');
            %grid(app.UIAxes_Image, 'on');

        end
        
        function refreshTimeSeries(app)
            
            %% 波形プロットの更新

            %disp(class(app.cursor_x));

            Pix_max = permute(app.proxyImages_max(app.cursor_y, app.cursor_x, :), [3, 1, 2]);
            Pix_min = permute(app.proxyImages_min(app.cursor_y, app.cursor_x, :), [3, 1, 2]);
    
            t = ((1:app.proxyLength) - 1) / app.proxyLength * app.dulation;

            Temp_max = (Pix_max ./ 255) * (app.temp_max - app.temp_min) + app.temp_min;
            Temp_min = (Pix_min ./ 255) * (app.temp_max - app.temp_min) + app.temp_min;

            stairs(app.UIAxes_TemperPlot, t, Temp_max, 'b-');
            hold(app.UIAxes_TemperPlot, 'on');
            stairs(app.UIAxes_TemperPlot, t, Temp_min, 'b-');
            xline(app.UIAxes_TemperPlot, double(app.previewIdx - 1) * app.dulation / app.proxyLength, 'r-');
            hold(app.UIAxes_TemperPlot, 'off');
            
            box(app.UIAxes_TemperPlot, 'on');
            grid(app.UIAxes_TemperPlot, 'on');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %% スタートアップ処理
            
            app.Button_MakeProxy.Enable = false;
            app.Slider_ViewIndex.Enable = false;
            app.Slider_XCursor.Enable = false;
            app.Slider_YCursor.Enable = false;
            app.EditField_TempMax.Enable = false;
            app.EditField_TempMin.Enable = false;
            app.EditField_Dulations.Enable = false;
            app.Button_ViewDetail.Enable = false;
            app.Button_Export.Enable = false;

            axis(app.UIAxes_Image, 'equal');
            box(app.UIAxes_Image, 'on');
        end

        % Button pushed function: Button_SelectVideoFile
        function Button_SelectVideoFilePushed(app, event)
            
            %% ファイル読み込み
            
            [filename, path] = uigetfile('*', 'Select Video File.');
            if filename == 0
                return;
            end
            if path == 0
                return;
            end
            filenameWithPath = strcat(path, filename);
            app.Label_videoFileName.Text = 'Opening..';
            app.Label_statusMessage.Text = 'Opening..';
            try
                v = VideoReader(filenameWithPath);
            catch
                app.Label_videoFileName.Text = '-';
                app.Label_statusMessage.Text = 'Failed to read video file.';
                return;
            end
            %disp(v);
            app.videoObj = v;
            app.Label_statusMessage.Text = strcat("Number of frames =  ", num2str(v.NumFrames), '.');
            app.Label_videoFileName.Text = filename;
            app.dulation = round(v.NumFrames / v.FrameRate / 60);
            app.EditField_Dulations.Value = app.dulation;
            app.Button_MakeProxy.Enable = true;
        end

        % Button pushed function: Button_MakeProxy
        function Button_MakeProxyPushed(app, event)
            
            %% プロキシ生成
            
            app.decimateNum = app.EditField_decimateNum.Value;
            idxMax = floor(app.videoObj.NumFrames / app.decimateNum);
            app.proxyImages_max = [];
            app.proxyImages_min = [];
            for idx = 1:idxMax
                frameIdx_start = (idx - 1) * app.decimateNum + 1;
                frameIdx_end = idx * app.decimateNum;
                sectionFrames = read(app.videoObj, [frameIdx_start, frameIdx_end]); % [height, width, depth, framecount]
                sectionFrames = permute(sectionFrames(:, :, 1, :), [4, 1, 2, 3]); % [framecount, height, width]
                frame_max = permute(max(sectionFrames), [2, 3, 1]); % [height, width]
                frame_min = permute(min(sectionFrames), [2, 3, 1]);
                app.proxyImages_max(:, :, idx) = frame_max;
                app.proxyImages_min(:, :, idx) = frame_min;
                app.Label_statusMessage.Text = strcat(num2str(idx), ' / ', num2str(idxMax));
                %export2wsdlg({'Save'}, {'sectionFrames'}, {app.proxyImages_max});
            end
            app.Label_statusMessage.Text = 'Coompleted.';
            app.proxyLength = idxMax;
            
            % 表示更新
            %image = (app.proxyImages_max(:, :, 1) + app.proxyImages_min(:, :, 1)) ./ 2;
            %imshow(uint8(image), 'Parent', app.UIAxes_Image);
            app.previewIdx = 1;
            refreshPreviewImage(app);
            refreshTimeSeries(app);

            % スライダーの設定
            app.Slider_ViewIndex.Limits = [1, idxMax];
            app.Slider_ViewIndex.Enable = true;
            app.Slider_XCursor.Limits = [1, app.videoObj.Width];
            app.Slider_XCursor.Enable = true;
            app.Slider_YCursor.Limits = [-app.videoObj.Height, -1];
            app.Slider_YCursor.Enable = true;

            % 各種設定許可
            app.EditField_TempMax.Enable = true;
            app.EditField_TempMin.Enable = true;
            app.EditField_Dulations.Enable = true;
            app.Button_ViewDetail.Enable = true;
            app.Button_Export.Enable = true;
        end

        % Value changing function: Slider_ViewIndex
        function Slider_ViewIndexValueChanging(app, event)
            changingValue = event.Value;
            app.previewIdx = int32(round(changingValue));
            %image = (app.proxyImages_max(:, :, ival) + app.proxyImages_min(:, :, ival)) ./ 2;
            %imshow(uint8(image), 'Parent', app.UIAxes_Image);
            refreshPreviewImage(app);
            refreshTimeSeries(app);
        end

        % Value changing function: Slider_XCursor
        function Slider_XCursorValueChanging(app, event)
            changingValue = event.Value;
            app.cursor_x = int32(round(changingValue));
            app.Label_statusMessage.Text = strcat('cursor=[', num2str(app.cursor_x), ', ', num2str(app.cursor_y), ']');
            refreshPreviewImage(app);
            refreshTimeSeries(app);
        end

        % Value changing function: Slider_YCursor
        function Slider_YCursorValueChanging(app, event)
            changingValue = event.Value;
            app.cursor_y = int32(round(-changingValue));
            app.Label_statusMessage.Text = strcat('cursor=[', num2str(app.cursor_x), ', ', num2str(app.cursor_y), ']');
            refreshPreviewImage(app);
            refreshTimeSeries(app);
        end

        % Value changed function: EditField_TempMax
        function EditField_TempMaxValueChanged(app, event)
            value = app.EditField_TempMax.Value;
            app.temp_max = value;
            refreshTimeSeries(app);
        end

        % Value changed function: EditField_TempMin
        function EditField_TempMinValueChanged(app, event)
            value = app.EditField_TempMin.Value;
            app.temp_min = value;
            refreshTimeSeries(app);
        end

        % Value changed function: EditField_Dulations
        function EditField_DulationsValueChanged(app, event)
            value = app.EditField_Dulations.Value;
            app.dulation = value;
            refreshTimeSeries(app);
        end

        % Button pushed function: Button_ViewDetail
        function Button_ViewDetailPushed(app, event)
            
            %% 詳細表示
            
            cx = app.cursor_x;
            cy = app.cursor_y;
            app.detail_location = [cx, cy];
            Temp_max = app.temp_max;
            Temp_min = app.temp_min;
            Dur = app.dulation;
            app.detail_time = ((1:app.videoObj.NumFrames) - 1) ./ app.videoObj.NumFrames * Dur;
            app.detail_temp = zeros([1, app.videoObj.NumFrames]);
            for idx = 1:app.videoObj.NumFrames
                rawFrame = read(app.videoObj, idx);
                pix = rawFrame(cy, cx, 1);
                app.detail_temp(idx) = double(pix) / 255 * (Temp_max - Temp_min) + Temp_min;
                app.Label_statusMessage.Text = strcat('Making detail view..(', num2str(round(idx / app.videoObj.NumFrames * 100)), '%)');
            end
            plot(app.UIAxes_TemperPlot, app.detail_time, app.detail_temp);
            xline(app.UIAxes_TemperPlot, double(app.previewIdx - 1) * app.dulation / app.proxyLength, 'r-');
            %export2wsdlg({'Save', 'Save'}, {'detail_time', 'detail_temp'}, {app.detail_time, app.detail_temp});
        end

        % Button pushed function: Button_Export
        function Button_ExportPushed(app, event)
            
            %% 結果書き出し
            
            result = [];
            result.time = app.detail_time;
            result.temp = app.detail_temp;
            result.location = app.detail_location;
            result.previewTime = double(app.previewIdx - 1) * app.dulation / app.proxyLength;
            result.previewProxyFrameIndex = app.previewIdx;
            result.previewProxyImage = (app.proxyImages_max(:, :, app.previewIdx) + app.proxyImages_min(:, :, app.previewIdx)) ./ 2;
            result.temp_lim = [app.temp_min, app.temp_max];
            result.dulation = app.dulation;
            result.videoObject = app.videoObj;
            export2wsdlg({'Save to workspace'}, {'thermocam'}, {result});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThermoCamAnalyzerUIFigure = uifigure('Visible', 'off');
            app.ThermoCamAnalyzerUIFigure.Name = 'ThermoCamAnalyzer';
            app.ThermoCamAnalyzerUIFigure.Icon = 'ico.jpg';
            app.Panel_3 = uipanel(app.ThermoCamAnalyzerUIFigure);
            app.Button_SelectVideoFile = uibutton(app.Panel_3, 'push');
            app.Button_SelectVideoFile.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectVideoFilePushed, true);
            app.Button_SelectVideoFile.Text = {'Select'; 'Video File'};
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '➡';
            app.Panel = uipanel(app.Panel_3);
            app.DecimateCountLabel = uilabel(app.Panel);
            app.DecimateCountLabel.Text = {'Decimate'; 'Count'};
            app.EditField_decimateNum = uieditfield(app.Panel, 'numeric');
            app.EditField_decimateNum.Limits = [1 Inf];
            app.EditField_decimateNum.Value = 100;
            app.Button_MakeProxy = uibutton(app.Panel, 'push');
            app.Button_MakeProxy.ButtonPushedFcn = createCallbackFcn(app, @Button_MakeProxyPushed, true);
            app.Button_MakeProxy.Text = 'Make Proxy';
            app.Label_2 = uilabel(app.Panel_3);
            app.Label_2.Text = '➡';
            app.Panel_2 = uipanel(app.Panel_3);
            app.TempMaxEditFieldLabel = uilabel(app.Panel_2);
            app.TempMaxEditFieldLabel.Text = 'Temp Max';
            app.EditField_TempMax = uieditfield(app.Panel_2, 'numeric');
            app.EditField_TempMax.ValueChangedFcn = createCallbackFcn(app, @EditField_TempMaxValueChanged, true);
            app.EditField_TempMax.Value = 100;
            app.TempMinEditFieldLabel = uilabel(app.Panel_2);
            app.TempMinEditFieldLabel.Text = 'Temp Min';
            app.EditField_TempMin = uieditfield(app.Panel_2, 'numeric');
            app.EditField_TempMin.ValueChangedFcn = createCallbackFcn(app, @EditField_TempMinValueChanged, true);
            app.DulationLabel = uilabel(app.Panel_2);
            app.DulationLabel.Text = 'Dulation[min]';
            app.EditField_Dulations = uieditfield(app.Panel_2, 'numeric');
            app.EditField_Dulations.Limits = [1 Inf];
            app.EditField_Dulations.ValueChangedFcn = createCallbackFcn(app, @EditField_DulationsValueChanged, true);
            app.EditField_Dulations.Value = 1;
            app.Label_3 = uilabel(app.Panel_3);
            app.Label_3.Text = '➡';
            app.Button_ViewDetail = uibutton(app.Panel_3, 'push');
            app.Button_ViewDetail.ButtonPushedFcn = createCallbackFcn(app, @Button_ViewDetailPushed, true);
            app.Button_ViewDetail.Text = {'View'; 'Detail'};
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '➡';
            app.Button_Export = uibutton(app.Panel_3, 'push');
            app.Button_Export.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportPushed, true);
            app.Button_Export.Text = {'Export'; 'to'; 'Workspace'};
            app.Panel_TimeSeries = uipanel(app.ThermoCamAnalyzerUIFigure);
            app.Panel_TimeSeries.Title = 'Time Series';
            app.UIAxes_TemperPlot = uiaxes(app.Panel_TimeSeries);
            title(app.UIAxes_TemperPlot, {''; ''})
            xlabel(app.UIAxes_TemperPlot, {'Time'; ''})
            ylabel(app.UIAxes_TemperPlot, 'Temp.')
            zlabel(app.UIAxes_TemperPlot, 'Z')
            app.CountSliderLabel = uilabel(app.Panel_TimeSeries);
            app.CountSliderLabel.Text = 'Count';
            app.Slider_ViewIndex = uislider(app.Panel_TimeSeries);
            app.Slider_ViewIndex.ValueChangingFcn = createCallbackFcn(app, @Slider_ViewIndexValueChanging, true);
            app.Panel_ImagePreview = uipanel(app.ThermoCamAnalyzerUIFigure);
            app.Panel_ImagePreview.Title = 'Image Preview';
            app.UIAxes_Image = uiaxes(app.Panel_ImagePreview);
            app.UIAxes_Image.XLim = [0 640];
            app.UIAxes_Image.YLim = [0 480];
            app.UIAxes_Image.XTick = [];
            app.UIAxes_Image.YTick = [];
            app.Slider_YCursor = uislider(app.Panel_ImagePreview);
            app.Slider_YCursor.MajorTicks = [];
            app.Slider_YCursor.ValueChangingFcn = createCallbackFcn(app, @Slider_YCursorValueChanging, true);
            app.Slider_XCursor = uislider(app.Panel_ImagePreview);
            app.Slider_XCursor.MajorTicks = [];
            app.Slider_XCursor.ValueChangingFcn = createCallbackFcn(app, @Slider_XCursorValueChanging, true);
            app.Label_videoFileName = uilabel(app.ThermoCamAnalyzerUIFigure);
            app.Label_videoFileName.Text = '-';
            app.Label_statusMessage = uilabel(app.ThermoCamAnalyzerUIFigure);
            app.Label_statusMessage.Text = '';
            % Show the figure after all components are created
            app.ThermoCamAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thermo_cam_analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThermoCamAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThermoCamAnalyzerUIFigure)
        end
    end
end